classdef EigenmodeCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulatorTab                    matlab.ui.container.Tab
        MultiwireSimulatorLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        CalculateEigenmodesButton       matlab.ui.control.Button
        Conductor1Label                 matlab.ui.control.Label
        Conductor2Label                 matlab.ui.control.Label
        Conductor3Label                 matlab.ui.control.Label
        Conductor4Label                 matlab.ui.control.Label
        GNDPlaneCheckBox                matlab.ui.control.CheckBox
        FrequenciesButtonGroup          matlab.ui.container.ButtonGroup
        KHzButton                       matlab.ui.control.RadioButton
        MHzButton                       matlab.ui.control.RadioButton
        GHzButton                       matlab.ui.control.RadioButton
        StartFrequencyLabel             matlab.ui.control.Label
        StartFrequencySpinner           matlab.ui.control.Spinner
        EndFrequencySpinnerLabel        matlab.ui.control.Label
        EndFrequencySpinner             matlab.ui.control.Spinner
        NumberofSamplesLabel            matlab.ui.control.Label
        NumberofSamplesSpinner          matlab.ui.control.Spinner
        RelativePermittivityLabel       matlab.ui.control.Label
        RelativePermittivitySpinner     matlab.ui.control.Spinner
        ypositionEditFieldLabel_2       matlab.ui.control.Label
        ypositionConductor1             matlab.ui.control.NumericEditField
        xpositionEditFieldLabel_2       matlab.ui.control.Label
        xpositionConductor1             matlab.ui.control.NumericEditField
        xpositionEditFieldLabel_3       matlab.ui.control.Label
        xpositionConductor2             matlab.ui.control.NumericEditField
        xpositionEditField_2Label_2     matlab.ui.control.Label
        xpositionConductor3             matlab.ui.control.NumericEditField
        xpositionEditField_4Label_2     matlab.ui.control.Label
        xpositionConductor4             matlab.ui.control.NumericEditField
        ypositionEditFieldLabel_3       matlab.ui.control.Label
        ypositionConductor2             matlab.ui.control.NumericEditField
        ypositionEditFieldLabel_4       matlab.ui.control.Label
        ypositionConductor3             matlab.ui.control.NumericEditField
        ypositionEditFieldLabel_5       matlab.ui.control.Label
        ypositionConductor4             matlab.ui.control.NumericEditField
        ypositionLabel                  matlab.ui.control.Label
        ypositionGND                    matlab.ui.control.NumericEditField
        NumberOfConductorsSpinnerLabel  matlab.ui.control.Label
        NumberOfConductorsSpinner       matlab.ui.control.Spinner
        UnitsButtonGroup                matlab.ui.container.ButtonGroup
        mmButton                        matlab.ui.control.RadioButton
        cmButton                        matlab.ui.control.RadioButton
        ConductorRadiusLabel            matlab.ui.control.Label
        ConductorRadiusEditField        matlab.ui.control.NumericEditField
        LossTangentLabel                matlab.ui.control.Label
        LossTanSpinner                  matlab.ui.control.Spinner
        ConductivitySmEditFieldLabel    matlab.ui.control.Label
        ConductivitySmEditField         matlab.ui.control.NumericEditField
        SurroundingMediumLabel          matlab.ui.control.Label
        ThicknessEditFieldLabel         matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        CoatedWiresCheckBox             matlab.ui.control.CheckBox
        RelativePermittivitySpinner_2Label  matlab.ui.control.Label
        RelativePermittivityCoatingSpinner  matlab.ui.control.Spinner
        LossTangentLabel_2              matlab.ui.control.Label
        LossTanCoatingSpinner           matlab.ui.control.Spinner
        SaveButton                      matlab.ui.control.Button
        SaveResultsCheckBox             matlab.ui.control.CheckBox
        SavePathLabel                   matlab.ui.control.Label
        ModeVoltagesTab                 matlab.ui.container.Tab
        UIAxesMode1                     matlab.ui.control.UIAxes
        UIAxesMode2                     matlab.ui.control.UIAxes
        UIAxesMode3                     matlab.ui.control.UIAxes
        UIAxesMode4                     matlab.ui.control.UIAxes
        HideLegendsCheckBox             matlab.ui.control.CheckBox
        ModePropagationTab              matlab.ui.container.Tab
        UIAxesZc                        matlab.ui.control.UIAxes
        UIAxesNeff                      matlab.ui.control.UIAxes
        UIAxesLoss                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SavePathSelected=0; % Bool, Set if valid path has been selected
        SaveFileFullPath; % Description
    end


    methods (Access = private)
        
        function ResetGeometry(app) 
                    app.xpositionConductor1.Enable='off';
                    app.ypositionConductor1.Enable='off';
                    app.xpositionConductor2.Enable='off';
                    app.ypositionConductor2.Enable='off';
                    app.xpositionConductor3.Enable='off';
                    app.ypositionConductor3.Enable='off';
                    app.xpositionConductor4.Enable='off';
                    app.ypositionConductor4.Enable='off';
        end
        
        function pos_vec = ReadXYPositions(app)
            % Returns x,y position as column vector
            Nconductors=app.NumberOfConductorsSpinner.Value;
            pos_vec=zeros(2, Nconductors); 
            switch Nconductors
                case 1
                    pos_vec(1,1)=app.xpositionConductor1.Value;
                    pos_vec(2,1)=app.ypositionConductor1.Value;
                case 2         
                    pos_vec(1,1)=app.xpositionConductor1.Value;
                    pos_vec(2,1)=app.ypositionConductor1.Value;
                    pos_vec(1,2)=app.xpositionConductor2.Value;
                    pos_vec(2,2)=app.ypositionConductor2.Value;
                case 3
                    pos_vec(1,1)=app.xpositionConductor1.Value;
                    pos_vec(2,1)=app.ypositionConductor1.Value;
                    pos_vec(1,2)=app.xpositionConductor2.Value;
                    pos_vec(2,2)=app.ypositionConductor2.Value;
                    pos_vec(1,3)=app.xpositionConductor3.Value;
                    pos_vec(2,3)=app.ypositionConductor3.Value;
                case 4
                    pos_vec(1,1)=app.xpositionConductor1.Value;
                    pos_vec(2,1)=app.ypositionConductor1.Value;
                    pos_vec(1,2)=app.xpositionConductor2.Value;
                    pos_vec(2,2)=app.ypositionConductor2.Value;
                    pos_vec(1,3)=app.xpositionConductor3.Value;
                    pos_vec(2,3)=app.ypositionConductor3.Value;
                    pos_vec(1,4)=app.xpositionConductor4.Value;
                    pos_vec(2,4)=app.ypositionConductor4.Value;
                otherwise
                    pos_vec=zeros(2,0);
            end
            if app.mmButton.Value
                pos_vec=pos_vec*1e-3;
            elseif app.cmButton.Value
                pos_vec=pos_vec*1e-2;
            end
        end

        function updateGeometry(app)
            positions=ReadXYPositions(app);
            radius=getRadius(app);
            N_cond=length(positions(1,:));
            [x,y]=circles(positions, radius);
            plot(app.UIAxes, x,y)       
            if app.CoatedWiresCheckBox.Value 
                b=getThickness(app)+radius; 
                [x,y]=circles(positions, b); 
                hold(app.UIAxes,'on')
                plot(app.UIAxes, x,y, ':k'); 
                hold(app.UIAxes,'off')%plot Coatings as dotted lines
            end
            
            text(app.UIAxes,positions(1,:),positions(2,:), num2str((1:N_cond).') )
            sca=max([max(x),max(y), abs(min(x)), abs(min(y))]);           
            if ~isempty(sca); xlim(app.UIAxes,[-sca,sca]); ylim(app.UIAxes,[-sca,sca]); 
                if app.GNDPlaneCheckBox.Value
                    y_GND=getGND_y(app);
                    line(app.UIAxes,[-sca,sca], [y_GND, y_GND], 'Color','black','LineStyle','--');
                end
            end
            
            function [x,y]=circles(centre, radius)
                x=centre(1,:)+(cos(linspace(0,2*pi,100))*radius).';
                y=centre(2,:)+(sin(linspace(0,2*pi,100))*radius).';
            end
        end
        
        
        function f = getFrequencies(app)
            if app.KHzButton.Value
                Multiplier=1e3;
            elseif app.MHzButton.Value
                Multiplier=1e6;
            elseif app.GHzButton.Value
                Multiplier=1e9;
            end
            f_Start=app.StartFrequencySpinner.Value*Multiplier;
            f_End=app.EndFrequencySpinner.Value*Multiplier; 
            nSamples=app.NumberofSamplesSpinner.Value;
            f=linspace(f_Start, f_End, nSamples);
        end
        
        function r = getRadius(app)
            r=app.ConductorRadiusEditField.Value;
            if app.mmButton.Value
                r=r*1e-3;
            elseif app.cmButton.Value
                r=r*1e-2;
            end
        end
        
        function y = getGND_y(app)
        y= app.ypositionGND.Value;
        if app.mmButton.Value
            y=y*1e-3;
        elseif app.cmButton.Value
            y=y*1e-2;
        end
        end
        
        function UpdateModePlot(app, V_modal)
            f=getFrequencies(app);
            nModes=app.NumberOfConductorsSpinner.Value;
            labels=cell(1,nModes);
            for ind=1:nModes
                labels{1,ind}=strcat('Conductor ', num2str(ind)); 
            end

      
            axhandles=[app.UIAxesMode1, app.UIAxesMode2, app.UIAxesMode3, app.UIAxesMode4];
            cla(axhandles(1));cla(axhandles(2)); cla(axhandles(3)); cla(axhandles(4));
            for idx=1:nModes
                ax=axhandles(idx);
                signs=sign(real(squeeze(V_modal(:,idx,1)))); %Use Mode defintion at first Frequency point to determine signs - Note that current and voltage signs can be switched leading to ambiguity
                plot(ax,f, signs.*abs(squeeze(V_modal(:,idx,:)))); 
                ylim(ax, [-1,1]);
                legend(ax, labels, 'FontSize', 10)
            end
        end
        
        function UpdateCharacteristicsPlot(app,Zc, be)
            c=299792458;
            f=getFrequencies(app);
            k0=2*pi*f/c;
            nModes=length(Zc(:,1)); 
            labels=cell(1,nModes);
            for ind=1:nModes
                labels{1,ind}=strcat('Mode ', num2str(ind)); 
            end
            
            % Plot Characteristic Impedance
            plot(app.UIAxesZc,f,real(Zc)); 
            legend(app.UIAxesZc, labels, 'Location', "eastoutside");
            
            % Plot Loss
            loss_dB=-imag(be)*20*log10(exp(1));
            plot(app.UIAxesLoss, f,loss_dB); 
            
            % Plot N_eff
            neff=real(be)./k0;
            plot(app.UIAxesNeff, f,neff); 
        end
        
        function result = ChkInput(app) %Returns 0 if there is a problem with Coating input
            result=true;
            if app.NumberOfConductorsSpinner.Value<1
                result=false; messg='Need to define at least one conductor'; 
            end            
            if isinf(app.ConductivitySmEditField.Value)
                result=false; messg='Please use a finite conductivity to avoid problems with degenerate modes';
            end
            %Check Input Specific for coated wires
            if app.CoatedWiresCheckBox.Value
                if app.ThicknessEditField<=0
                    result=false; messg="Thickness must be positive and non-zero"; 
                end 
                if app.RelativePermittivityCoatingSpinner.Value<=app.RelativePermittivitySpinner.Value
                    result=false; messg="Permittivity of Coating must be greater than permittivity of surrounding material";
                end
            end
            
            if ~result %in case of error through message
                uialert(app.UIFigure, messg,"error");
            end
        end
        
        function thick=getThickness(app)
            thick=app.ThicknessEditField.Value; 
            if app.mmButton.Value
                thick=thick*1e-3;
            elseif app.cmButton.Value
                thick=thick*1e-2;
            end
        end
        
        function [a,b,ed_diel, LossTanDiel] = getCoatingValues(app)
            a=getRadius(app);
            thick=getThickness(app);
            b=a+thick; 
            ed_diel=app.RelativePermittivityCoatingSpinner.Value; 
            LossTanDiel=app.LossTanCoatingSpinner.Value;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberOfConductorsSpinner
        function NumberOfConductorsSpinnerValueChanged(app, event)
            value = app.NumberOfConductorsSpinner.Value;
            switch value
                case 0
                   ResetGeometry(app);
                case 1
                    ResetGeometry(app);
                    app.xpositionConductor1.Enable='on';
                    app.ypositionConductor1.Enable='on';
                case 2                         
                    ResetGeometry(app);
                    app.xpositionConductor1.Enable='on';
                    app.ypositionConductor1.Enable='on';
                    app.xpositionConductor2.Enable='on';
                    app.ypositionConductor2.Enable='on';
                case 3
                    ResetGeometry(app);
                    app.xpositionConductor1.Enable='on';
                    app.ypositionConductor1.Enable='on';
                    app.xpositionConductor2.Enable='on';
                    app.ypositionConductor2.Enable='on';
                    app.xpositionConductor3.Enable='on';
                    app.ypositionConductor3.Enable='on';
                case 4
                    ResetGeometry(app);
                    app.xpositionConductor1.Enable='on';
                    app.ypositionConductor1.Enable='on';
                    app.xpositionConductor2.Enable='on';
                    app.ypositionConductor2.Enable='on';
                    app.xpositionConductor3.Enable='on';
                    app.ypositionConductor3.Enable='on';
                    app.xpositionConductor4.Enable='on';
                    app.ypositionConductor4.Enable='on';
            end
            updateGeometry(app)
        end

        % Value changed function: ConductorRadiusEditField
        function ConductorRadiusEditFieldValueChanged(app, event)
            updateGeometry(app)
        end

        % Value changed function: xpositionConductor1
        function xpositionConductor1ValueChanged(app, event)
            updateGeometry(app);
        end

        % Value changed function: ypositionConductor1
        function ypositionConductor1ValueChanged(app, event)
            updateGeometry(app);
        end

        % Value changed function: xpositionConductor2
        function xpositionConductor2ValueChanged(app, event)
            updateGeometry(app);
        end

        % Value changed function: xpositionConductor3
        function xpositionConductor3ValueChanged(app, event)
           updateGeometry(app);
        end

        % Value changed function: xpositionConductor4
        function xpositionConductor4ValueChanged(app, event)
           updateGeometry(app);
        end

        % Value changed function: ypositionConductor2
        function ypositionConductor2ValueChanged(app, event)
            updateGeometry(app);
        end

        % Value changed function: ypositionConductor3
        function ypositionConductor3ValueChanged(app, event)
            updateGeometry(app);
        end

        % Value changed function: ypositionConductor4
        function ypositionConductor4ValueChanged(app, event)
            updateGeometry(app);
        end

        % Selection changed function: UnitsButtonGroup
        function UnitsButtonGroupSelectionChanged(app, event)
            app.UnitsButtonGroup.SelectedObject;
                updateGeometry(app);
        end

        % Value changed function: GNDPlaneCheckBox
        function GNDPlaneCheckBoxValueChanged(app, event)
            value = app.GNDPlaneCheckBox.Value;
            if value
                app.ypositionGND.Enable='on';       
                app.ypositionLabel.Enable="on";
            else
                app.ypositionGND.Enable='off';      
                app.ypositionLabel.Enable="off";
            end
            updateGeometry(app);
        end

        % Button pushed function: CalculateEigenmodesButton
        function CalculateEigenmodesButtonPushed(app, event)
            InputFlag=ChkInput(app);
            if InputFlag    
                nConductors=app.NumberOfConductorsSpinner.Value;
                eps0=8.854187812*1e-12;
    
                app.TabGroup.SelectedTab=app.ModeVoltagesTab;
                f=getFrequencies(app); 
                nFrequencies=length(f);
                
                be_all=zeros(nConductors, nFrequencies);
                Zc_all=zeros(nConductors, nFrequencies);
                V_modal_all=zeros(nConductors, nConductors, nFrequencies);
                I_modal_all=zeros(nConductors, nConductors, nFrequencies);
                r=getRadius(app);
                pos_vec = ReadXYPositions(app);
                ed=app.RelativePermittivitySpinner.Value;
                lossTan=app.LossTanSpinner.Value; 
                sigma_cond=app.ConductivitySmEditField.Value;
                
                if app.GNDPlaneCheckBox.Value, h=getGND_y(app);pos_vec(2,:)=pos_vec(2,:)-h; end %Height defined as negative
                if app.CoatedWiresCheckBox.Value, [a,b,ed_diel,LossTanDiel]=getCoatingValues(app); end
    
                for idx=1:nFrequencies
                    freq=f(idx);
                    sigma_diel=lossTan*ed*(2*pi*freq)*eps0;
                    if app.GNDPlaneCheckBox.Value && ~app.CoatedWiresCheckBox.Value %GND, no Coating
                        [R,C,L,G]=CalculateRCLG_uncoated(freq,pos_vec, r,  ed, sigma_diel, sigma_cond);
                        %[R,C,L,G]=CalculateRCLG_uncoated_oldTheory(freq, pos_vec, r,  ed, sigma_diel, sigma_cond);
                    elseif app.GNDPlaneCheckBox.Value && app.CoatedWiresCheckBox.Value %GND,  Coating
                        [R,C,L,G] = CalculateRCLG_coated(freq, pos_vec, a, b,  ed_diel,  sigma_cond, LossTanDiel);
                        %[R,C,L,G] = CalculateRCLG_coated_oldTheory(freq, pos_vec, a, b,  ed_diel,  sigma_cond, LossTanDiel);
                    elseif ~app.GNDPlaneCheckBox.Value && app.CoatedWiresCheckBox.Value %NO GND,  Coating
                        [R,C,L,G] = CalculateRCLG_coated_NoGND(freq, pos_vec, a, b,  ed_diel,  sigma_cond,LossTanDiel);
                    elseif ~app.GNDPlaneCheckBox.Value && ~app.CoatedWiresCheckBox.Value %NO GND, no Coating
                        [R,C,L,G]=CalculateRCLG_uncoated_noGND(freq, pos_vec, r, ed, sigma_diel, sigma_cond);
                        %[R,C,L,G]=CalculateRCLG_uncoated_noGND_changed(freq, pos_vec, r, ed, sigma_diel, sigma_cond);
                    else 
                        error("Unknown Input detected - calculation not possible");
                    end
                     %R=0; %DEBUG - useful to make line lossless
                    [~,be,Zc,V_modal,I_modal] = CalculateEigenmodes(R,C,L,G,freq);
                    
                    be_all(:,idx)=diag(be);
                    Zc_all(:,idx)=diag(Zc);
                    V_modal_all(:,:,idx)=V_modal;
                    I_modal_all(:,:,idx)=I_modal;
                    
                end
                %dlmwrite("beta_single_coated.csv", [f.',real(be_all.'), imag(be_all.')], 'Delimiter', ',', 'precision',10, '-append')
                %dlmwrite("beta_distance.csv", [norm(pos_vec(:,1)-pos_vec(:,2)),real(be_all.'), imag(be_all.')], 'Delimiter', ';', 'precision',10, '-append')
                %dlmwrite("impCoated.csv", [(norm(pos_vec(:,1)-pos_vec(:,2)))*1e3,real(Zc_all.')], 'Delimiter', ';', 'precision',10, '-append')
                %PlotResultsEigenmodes(f,Zc_all, be_all, V_modal_all, I_modal_all) ;
                UpdateModePlot(app, V_modal_all)
                UpdateCharacteristicsPlot(app, Zc_all, be_all); 
                if app.SavePathSelected
                    save(app.SaveFileFullPath, "f", "V_modal_all", "I_modal_all", "Zc_all", "be_all")
                end
            end
            
        end

        % Value changed function: HideLegendsCheckBox
        function HideLegendsCheckBoxValueChanged(app, event)
            value = app.HideLegendsCheckBox.Value;
            axhandles=[app.UIAxesMode1, app.UIAxesMode2, app.UIAxesMode3, app.UIAxesMode4];
            for idx=1:4
                if value
                    set(axhandles(idx).Legend, 'Visible', 'off')
                else 
                    set(axhandles(idx).Legend, 'Visible', 'on')
                end
            end
        end

        % Value changed function: CoatedWiresCheckBox
        function CoatedWiresCheckBoxValueChanged(app, event)
            value = app.CoatedWiresCheckBox.Value;
            if value %Ticked
                app.RelativePermittivitySpinner.Value=1;
                app.RelativePermittivitySpinner.Enable="off";
                app.LossTanSpinner.Value=0; 
                app.LossTanSpinner.Enable="off";
                
                app.ThicknessEditField.Enable="on";
                app.ThicknessEditFieldLabel.Enable="on";
                app.RelativePermittivityCoatingSpinner.Enable="on";
                app.RelativePermittivitySpinner_2Label.Enable="on";
                app.LossTangentLabel_2.Enable="on";
                app.LossTanCoatingSpinner.Enable="on"; %dielectric loss not yet implemented
            else
                app.RelativePermittivitySpinner.Enable="on";
                app.LossTanSpinner.Enable="on";
                app.ThicknessEditField.Enable="off";
                app.ThicknessEditFieldLabel.Enable="off";
                app.RelativePermittivityCoatingSpinner.Enable="off";
                app.RelativePermittivitySpinner_2Label.Enable="off";
                app.LossTangentLabel_2.Enable="off";
                app.LossTanCoatingSpinner.Enable="off";
            end
            updateGeometry(app); 
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            updateGeometry(app); 
        end

        % Value changed function: ypositionGND
        function ypositionGNDValueChanged(app, event)
            updateGeometry(app);
        end

        % Value changed function: SaveResultsCheckBox
        function SaveResultsCheckBoxValueChanged(app, event)
            value = app.SaveResultsCheckBox.Value;
            if value
                app.SaveButton.Enable="on";
                app.SavePathLabel.Visible="on";
            else 
                app.SaveButton.Enable="off"; 
                app.SavePathLabel.Visible="off";
                app.SavePathLabel.Text="SavePath:";
                app.SavePathSelected=0;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [SaveFileName, SaveFilePath]=uiputfile("*.mat", "Select File For Saving Output"); 
            figure(app.UIFigure);
            if ischar(SaveFileName) && ischar(SaveFilePath)
                app.SaveFileFullPath=strcat(SaveFilePath, SaveFileName);
                app.SavePathLabel.Text=app.SaveFileFullPath;
                app.SavePathSelected=1;
            else
                app.SavePathLabel.Text="SavePath:";
                app.SavePathSelected=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulatorTab = uitab(app.TabGroup);
            app.SimulatorTab.Title = 'Simulator';
            app.MultiwireSimulatorLabel = uilabel(app.SimulatorTab);
            app.MultiwireSimulatorLabel.Text = 'Multiwire Simulator';
            app.UIAxes = uiaxes(app.SimulatorTab);
            title(app.UIAxes, 'Geometry')
            xlabel(app.UIAxes, 'X [m]')
            ylabel(app.UIAxes, 'Y [m]')
            app.CalculateEigenmodesButton = uibutton(app.SimulatorTab, 'push');
            app.CalculateEigenmodesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateEigenmodesButtonPushed, true);
            app.CalculateEigenmodesButton.Text = {'Calculate Eigenmodes'; ''};
            app.Conductor1Label = uilabel(app.SimulatorTab);
            app.Conductor1Label.Text = 'Conductor 1:';
            app.Conductor2Label = uilabel(app.SimulatorTab);
            app.Conductor2Label.Text = 'Conductor 2:';
            app.Conductor3Label = uilabel(app.SimulatorTab);
            app.Conductor3Label.Text = 'Conductor 3:';
            app.Conductor4Label = uilabel(app.SimulatorTab);
            app.Conductor4Label.Text = 'Conductor 4:';
            app.GNDPlaneCheckBox = uicheckbox(app.SimulatorTab);
            app.GNDPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @GNDPlaneCheckBoxValueChanged, true);
            app.GNDPlaneCheckBox.Text = 'GND Plane';
            app.FrequenciesButtonGroup = uibuttongroup(app.SimulatorTab);
            app.FrequenciesButtonGroup.Title = 'Frequencies';
            app.KHzButton = uiradiobutton(app.FrequenciesButtonGroup);
            app.KHzButton.Text = 'KHz';
            app.MHzButton = uiradiobutton(app.FrequenciesButtonGroup);
            app.MHzButton.Text = 'MHz';
            app.MHzButton.Value = true;
            app.GHzButton = uiradiobutton(app.FrequenciesButtonGroup);
            app.GHzButton.Text = 'GHz';
            app.StartFrequencyLabel = uilabel(app.SimulatorTab);
            app.StartFrequencyLabel.Text = {'Start'; 'Frequency'};
            app.StartFrequencySpinner = uispinner(app.SimulatorTab);
            app.StartFrequencySpinner.Limits = [1 Inf];
            app.StartFrequencySpinner.Value = 100;
            app.EndFrequencySpinnerLabel = uilabel(app.SimulatorTab);
            app.EndFrequencySpinnerLabel.Text = {'End'; 'Frequency'};
            app.EndFrequencySpinner = uispinner(app.SimulatorTab);
            app.EndFrequencySpinner.Limits = [1 Inf];
            app.EndFrequencySpinner.Value = 200;
            app.NumberofSamplesLabel = uilabel(app.SimulatorTab);
            app.NumberofSamplesLabel.Text = {'Number of'; ' Samples'};
            app.NumberofSamplesSpinner = uispinner(app.SimulatorTab);
            app.NumberofSamplesSpinner.Limits = [1 Inf];
            app.NumberofSamplesSpinner.Value = 101;
            app.RelativePermittivityLabel = uilabel(app.SimulatorTab);
            app.RelativePermittivityLabel.Text = {'Relative'; ' Permittivity'};
            app.RelativePermittivitySpinner = uispinner(app.SimulatorTab);
            app.RelativePermittivitySpinner.Limits = [1 Inf];
            app.RelativePermittivitySpinner.Value = 1;
            app.ypositionEditFieldLabel_2 = uilabel(app.SimulatorTab);
            app.ypositionEditFieldLabel_2.Text = 'y-position';
            app.ypositionConductor1 = uieditfield(app.SimulatorTab, 'numeric');
            app.ypositionConductor1.Limits = [0 Inf];
            app.ypositionConductor1.ValueChangedFcn = createCallbackFcn(app, @ypositionConductor1ValueChanged, true);
            app.ypositionConductor1.Enable = 'off';
            app.ypositionConductor1.Value = 1;
            app.xpositionEditFieldLabel_2 = uilabel(app.SimulatorTab);
            app.xpositionEditFieldLabel_2.Text = 'x-position';
            app.xpositionConductor1 = uieditfield(app.SimulatorTab, 'numeric');
            app.xpositionConductor1.ValueChangedFcn = createCallbackFcn(app, @xpositionConductor1ValueChanged, true);
            app.xpositionConductor1.Enable = 'off';
            app.xpositionEditFieldLabel_3 = uilabel(app.SimulatorTab);
            app.xpositionEditFieldLabel_3.Text = 'x-position';
            app.xpositionConductor2 = uieditfield(app.SimulatorTab, 'numeric');
            app.xpositionConductor2.ValueChangedFcn = createCallbackFcn(app, @xpositionConductor2ValueChanged, true);
            app.xpositionConductor2.Enable = 'off';
            app.xpositionEditField_2Label_2 = uilabel(app.SimulatorTab);
            app.xpositionEditField_2Label_2.Text = 'x-position';
            app.xpositionConductor3 = uieditfield(app.SimulatorTab, 'numeric');
            app.xpositionConductor3.ValueChangedFcn = createCallbackFcn(app, @xpositionConductor3ValueChanged, true);
            app.xpositionConductor3.Enable = 'off';
            app.xpositionEditField_4Label_2 = uilabel(app.SimulatorTab);
            app.xpositionEditField_4Label_2.Text = 'x-position';
            app.xpositionConductor4 = uieditfield(app.SimulatorTab, 'numeric');
            app.xpositionConductor4.ValueChangedFcn = createCallbackFcn(app, @xpositionConductor4ValueChanged, true);
            app.xpositionConductor4.Enable = 'off';
            app.ypositionEditFieldLabel_3 = uilabel(app.SimulatorTab);
            app.ypositionEditFieldLabel_3.Text = 'y-position';
            app.ypositionConductor2 = uieditfield(app.SimulatorTab, 'numeric');
            app.ypositionConductor2.Limits = [0 Inf];
            app.ypositionConductor2.ValueChangedFcn = createCallbackFcn(app, @ypositionConductor2ValueChanged, true);
            app.ypositionConductor2.Enable = 'off';
            app.ypositionConductor2.Value = 1;
            app.ypositionEditFieldLabel_4 = uilabel(app.SimulatorTab);
            app.ypositionEditFieldLabel_4.Text = 'y-position';
            app.ypositionConductor3 = uieditfield(app.SimulatorTab, 'numeric');
            app.ypositionConductor3.Limits = [0 Inf];
            app.ypositionConductor3.ValueChangedFcn = createCallbackFcn(app, @ypositionConductor3ValueChanged, true);
            app.ypositionConductor3.Enable = 'off';
            app.ypositionConductor3.Value = 1;
            app.ypositionEditFieldLabel_5 = uilabel(app.SimulatorTab);
            app.ypositionEditFieldLabel_5.Text = 'y-position';
            app.ypositionConductor4 = uieditfield(app.SimulatorTab, 'numeric');
            app.ypositionConductor4.Limits = [0 Inf];
            app.ypositionConductor4.ValueChangedFcn = createCallbackFcn(app, @ypositionConductor4ValueChanged, true);
            app.ypositionConductor4.Enable = 'off';
            app.ypositionConductor4.Value = 1;
            app.ypositionLabel = uilabel(app.SimulatorTab);
            app.ypositionLabel.Enable = 'off';
            app.ypositionLabel.Text = 'y-position';
            app.ypositionGND = uieditfield(app.SimulatorTab, 'numeric');
            app.ypositionGND.Limits = [-Inf 0];
            app.ypositionGND.ValueChangedFcn = createCallbackFcn(app, @ypositionGNDValueChanged, true);
            app.ypositionGND.Enable = 'off';
            app.NumberOfConductorsSpinnerLabel = uilabel(app.SimulatorTab);
            app.NumberOfConductorsSpinnerLabel.Text = 'Number Of Conductors';
            app.NumberOfConductorsSpinner = uispinner(app.SimulatorTab);
            app.NumberOfConductorsSpinner.Limits = [0 4];
            app.NumberOfConductorsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberOfConductorsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberOfConductorsSpinnerValueChanged, true);
            app.UnitsButtonGroup = uibuttongroup(app.SimulatorTab);
            app.UnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitsButtonGroupSelectionChanged, true);
            app.UnitsButtonGroup.Title = 'Units';
            app.mmButton = uiradiobutton(app.UnitsButtonGroup);
            app.mmButton.Text = 'mm';
            app.mmButton.Value = true;
            app.cmButton = uiradiobutton(app.UnitsButtonGroup);
            app.cmButton.Text = 'cm';
            app.ConductorRadiusLabel = uilabel(app.SimulatorTab);
            app.ConductorRadiusLabel.Text = 'Conductor Radius';
            app.ConductorRadiusEditField = uieditfield(app.SimulatorTab, 'numeric');
            app.ConductorRadiusEditField.Limits = [0 Inf];
            app.ConductorRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ConductorRadiusEditFieldValueChanged, true);
            app.ConductorRadiusEditField.Value = 1;
            app.LossTangentLabel = uilabel(app.SimulatorTab);
            app.LossTangentLabel.Text = {'Loss '; 'Tangent'};
            app.LossTanSpinner = uispinner(app.SimulatorTab);
            app.LossTanSpinner.Limits = [0 Inf];
            app.ConductivitySmEditFieldLabel = uilabel(app.SimulatorTab);
            app.ConductivitySmEditFieldLabel.Text = 'Conductivity [S/m]';
            app.ConductivitySmEditField = uieditfield(app.SimulatorTab, 'numeric');
            app.ConductivitySmEditField.Limits = [0 Inf];
            app.ConductivitySmEditField.Value = 58000000;
            app.SurroundingMediumLabel = uilabel(app.SimulatorTab);
            app.SurroundingMediumLabel.Text = {'Surrounding'; 'Medium:'; ''};
            app.ThicknessEditFieldLabel = uilabel(app.SimulatorTab);
            app.ThicknessEditFieldLabel.Enable = 'off';
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.SimulatorTab, 'numeric');
            app.ThicknessEditField.Limits = [0 Inf];
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Enable = 'off';
            app.CoatedWiresCheckBox = uicheckbox(app.SimulatorTab);
            app.CoatedWiresCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoatedWiresCheckBoxValueChanged, true);
            app.CoatedWiresCheckBox.Text = 'Coated Wires';
            app.RelativePermittivitySpinner_2Label = uilabel(app.SimulatorTab);
            app.RelativePermittivitySpinner_2Label.Enable = 'off';
            app.RelativePermittivitySpinner_2Label.Text = {'Relative'; ' Permittivity'};
            app.RelativePermittivityCoatingSpinner = uispinner(app.SimulatorTab);
            app.RelativePermittivityCoatingSpinner.Limits = [1 Inf];
            app.RelativePermittivityCoatingSpinner.Enable = 'off';
            app.RelativePermittivityCoatingSpinner.Value = 1;
            app.LossTangentLabel_2 = uilabel(app.SimulatorTab);
            app.LossTangentLabel_2.Enable = 'off';
            app.LossTangentLabel_2.Text = {'Loss '; 'Tangent'};
            app.LossTanCoatingSpinner = uispinner(app.SimulatorTab);
            app.LossTanCoatingSpinner.Limits = [0 Inf];
            app.LossTanCoatingSpinner.Enable = 'off';
            app.SaveButton = uibutton(app.SimulatorTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Change Path';
            app.SaveResultsCheckBox = uicheckbox(app.SimulatorTab);
            app.SaveResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveResultsCheckBoxValueChanged, true);
            app.SaveResultsCheckBox.Text = 'Save Results';
            app.SavePathLabel = uilabel(app.SimulatorTab);
            app.SavePathLabel.Visible = 'off';
            app.SavePathLabel.Text = 'SavePath:';
            app.ModeVoltagesTab = uitab(app.TabGroup);
            app.ModeVoltagesTab.Title = 'Mode Voltages';
            app.UIAxesMode1 = uiaxes(app.ModeVoltagesTab);
            title(app.UIAxesMode1, 'Mode 1')
            xlabel(app.UIAxesMode1, 'Frequency [Hz]')
            ylabel(app.UIAxesMode1, 'Voltage [V]')
            app.UIAxesMode2 = uiaxes(app.ModeVoltagesTab);
            title(app.UIAxesMode2, 'Mode 2')
            xlabel(app.UIAxesMode2, 'Frequency [Hz]')
            ylabel(app.UIAxesMode2, 'Voltage [V]')
            app.UIAxesMode3 = uiaxes(app.ModeVoltagesTab);
            title(app.UIAxesMode3, 'Mode 3')
            xlabel(app.UIAxesMode3, 'Frequency [Hz]')
            ylabel(app.UIAxesMode3, 'Voltage [V]')
            app.UIAxesMode4 = uiaxes(app.ModeVoltagesTab);
            title(app.UIAxesMode4, 'Mode 4')
            xlabel(app.UIAxesMode4, 'Frequency [Hz]')
            ylabel(app.UIAxesMode4, 'Voltage [V]')
            app.HideLegendsCheckBox = uicheckbox(app.ModeVoltagesTab);
            app.HideLegendsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HideLegendsCheckBoxValueChanged, true);
            app.HideLegendsCheckBox.Text = 'Hide Legends';
            app.ModePropagationTab = uitab(app.TabGroup);
            app.ModePropagationTab.Title = 'Mode Propagation';
            app.UIAxesZc = uiaxes(app.ModePropagationTab);
            title(app.UIAxesZc, 'Characteristic Impedance')
            xlabel(app.UIAxesZc, 'Frequency [Hz]')
            ylabel(app.UIAxesZc, 'Z Char [\Omega]')
            app.UIAxesNeff = uiaxes(app.ModePropagationTab);
            title(app.UIAxesNeff, 'Refractive Index')
            xlabel(app.UIAxesNeff, 'Frequency [Hz]')
            ylabel(app.UIAxesNeff, 'n_{eff}')
            app.UIAxesLoss = uiaxes(app.ModePropagationTab);
            title(app.UIAxesLoss, 'Losses')
            xlabel(app.UIAxesLoss, 'Frequency [Hz]')
            ylabel(app.UIAxesLoss, 'Loss [dB/m]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EigenmodeCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end